classdef GuiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EResetButton            matlab.ui.control.StateButton
        Lamp_2                  matlab.ui.control.Lamp
        Lamp                    matlab.ui.control.Lamp
        EStopButton             matlab.ui.control.StateButton
        LoadNewPassportsButton  matlab.ui.control.StateButton
        SystemSwitch            matlab.ui.control.Switch
        SystemSwitchLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadNewPassportsButton
        function LoadNewPassportsButtonValueChanged(app, event)
            value = app.LoadNewPassportsButton.Value;
            if value == 0
                app.LoadNewPassportsButton.FontWeight = 'bold';
            else
                app.LoadNewPassportsButton.FontWeight = 'normal';
            end

        end

        % Value changed function: SystemSwitch
        function SystemSwitchValueChanged(app, event)
            value = app.SystemSwitch.Value;
            if strcmp(value,'Off')
            app.Lamp.Color = [0.8,0.8,0.8];
            else
                app.Lamp.Color = [0,1,0];
            end
        end

        % Value changed function: EStopButton
        function EStopButtonValueChanged(app, event)
            value = app.EStopButton.Value;
            if value == 0
                app.EStopButton.FontWeight = 'bold';
                app.Lamp_2.Color = [1,0,0];
            else
                app.EStopButton.FontWeight = 'normal';
            end
        end

        % Value changed function: EResetButton
        function EResetButtonValueChanged(app, event)
            value = app.EResetButton.Value;
            if value == 0
                app.EResetButton.FontWeight = 'bold';
                if app.EStopButton.Value == 1
                app.Lamp_2.Color = [0.8,0.8,0.8];
                end
            else
                app.EResetButton.FontWeight = 'normal';
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SystemSwitchLabel = uilabel(app.UIFigure);
            app.SystemSwitchLabel.Text = 'System Switch';
            app.SystemSwitch = uiswitch(app.UIFigure, 'slider');
            app.SystemSwitch.ValueChangedFcn = createCallbackFcn(app, @SystemSwitchValueChanged, true);
            app.LoadNewPassportsButton = uibutton(app.UIFigure, 'state');
            app.LoadNewPassportsButton.ValueChangedFcn = createCallbackFcn(app, @LoadNewPassportsButtonValueChanged, true);
            app.LoadNewPassportsButton.Text = 'Load New Passports';
            app.LoadNewPassportsButton.Value = true;
            app.EStopButton = uibutton(app.UIFigure, 'state');
            app.EStopButton.ValueChangedFcn = createCallbackFcn(app, @EStopButtonValueChanged, true);
            app.EStopButton.Text = 'E-Stop';
            app.EStopButton.Value = true;
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.EResetButton = uibutton(app.UIFigure, 'state');
            app.EResetButton.ValueChangedFcn = createCallbackFcn(app, @EResetButtonValueChanged, true);
            app.EResetButton.Text = 'E-Reset';
            app.EResetButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end